classdef app_smart_parking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        iNSTRUMENTATIONPANELPanel     matlab.ui.container.Panel
        ENTERSwitchLabel              matlab.ui.control.Label
        ENTERSwitch                   matlab.ui.control.ToggleSwitch
        EXITSwitchLabel               matlab.ui.control.Label
        EXITSwitch                    matlab.ui.control.ToggleSwitch
        STOPLampLabel                 matlab.ui.control.Label
        STOPLamp                      matlab.ui.control.Lamp
        READYLampLabel                matlab.ui.control.Label
        READYLamp                     matlab.ui.control.Lamp
        GOLabel                       matlab.ui.control.Label
        GOLamp                        matlab.ui.control.Lamp
        SERVOPOSGaugeLabel            matlab.ui.control.Label
        SERVOPOSGauge                 matlab.ui.control.SemicircularGauge
        EMERGENCYKNOBKnobLabel        matlab.ui.control.Label
        EMERGENCYKNOBKnob             matlab.ui.control.Knob
        ALERTMESSAGEEditFieldLabel    matlab.ui.control.Label
        ALERTMESSAGEEditField         matlab.ui.control.EditField
        AVAILABLESLOTSEditFieldLabel  matlab.ui.control.Label
        AVAILABLESLOTSEditField       matlab.ui.control.EditField
        SMARTPARKINGAPPLICATIONLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CONFIGURATIONPANELPanel       matlab.ui.container.Panel
        COMPORTDropDownLabel          matlab.ui.control.Label
        COMPORTDropDown               matlab.ui.control.DropDown
        BOARDDropDownLabel            matlab.ui.control.Label
        BOARDDropDown                 matlab.ui.control.DropDown
        SERVOPINDropDownLabel         matlab.ui.control.Label
        SERVOPINDropDown              matlab.ui.control.DropDown
        ConnectButton                 matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Arduino connection      
                    port=string(app.COMPORTDropDown.Value);
                    com_port=lower(port);
                    board=string(app.BOARDDropDown.Value);
                    arduino_board=lower(board);
                    if board=='mega2560'
                        board=[upper(board(1)),lower(board(2:lastIndex))];
                        return
                    end
                    servo_pin=string(app.SERVOPINDropDown.Value);
            function connectButtonPushed(~)
                arduino_target=arduino(com_port,arduino_board);
                servo_motor=servo(arduino_target,servo_pin);
                writePosition(servo_motor,1);
                
            end
           global parking_count
           parking_count=14;
           app.STOPLamp.Color=[1.00,0.00,0.00];
           app.ALERTMESSAGEEditField.Value="STOP";
           value1 = app.ENTERSwitch.Value;
           value2 = app.EXITSwitch.Value;
            for  parking_count=0:14
                                  
            switch value1
                case 'ON'
                app.SERVOPOSGauge.Value=90;
                %app.AVAILABLESLOTSEditField.Enable='on';
                app.ALERTMESSAGEEditField.Enable='on';
                app.STOPLamp.Color=[1.00,1.00,1.00];
                app.ALERTMESSAGEEditField.Value='Welcome';
                app.GOLamp.Color=[0.00,1.00,0.00];
                parking_count=parking_count+1;
                app.AVAILABLESLOTSEditField.Value=int2str(parking_count)
                break;
                case 'OFF'
                parking_count=parking_count;
                app.AVAILABLESLOTSEditField.Value=int2str(parking_count);
                app.STOPLamp.Color=[1.00,0.00,0.00];
                app.GOLamp.Color=[1.00,1.00,1.00];
                app.READYLamp.Color=[1.00,1.00,1.00];
                app.SERVOPOSGauge.Value=0;
                break;
                otherwise
                app.GOLamp.Color=[1.00,1.00,1.00];
                app.READYLamp.Color=[1.00,1.00,1.00];
                app.STOPLamp.Color=[1.00,1.00,1.00];
                app.SERVOPOSGauge.Value=0;
                
            end
            % Exit Toggle switch callback
             switch value2
                case 'ON'
                app.SERVOPOSGauge.Value=90;
                %app.AVAILABLESLOTSEditField.Enable='on';
                app.ALERTMESSAGEEditField.Enable='on';
                app.STOPLamp.Color=[1.00,1.00,1.00];
                app.ALERTMESSAGEEditField.Value='Goodbye';
                app.GOLamp.Color=[0.00,1.00,0.00];
                parking_count=parking_count-1;
                app.AVAILABLESLOTSEditField.Value=int2str(parking_count);
                break;
                case 'OFF'
                parking_count=parking_count;
                app.AVAILABLESLOTSEditField.Value=int2str(parking_count);
                app.STOPLamp.Color=[1.00,0.00,0.00];
                app.GOLamp.Color=[1.00,1.00,1.00];
                app.READYLamp.Color=[1.00,1.00,1.00];
                app.SERVOPOSGauge.Value=0;
                break;
                otherwise
                app.GOLamp.Color=[1.00,1.00,1.00];
                app.READYLamp.Color=[1.00,1.00,1.00];
                app.STOPLamp.Color=[1.00,1.00,1.00];
                app.SERVOPOSGauge.Value=0;
                
            end
                     
                    break;
            end
            
        end

        % Value changing function: EMERGENCYKNOBKnob
        function EMERGENCYKNOBKnobValueChanging(app, event)
            changingValue = event.Value;
            app.SERVOPOSGauge.Value=changingValue;
        end

        % Value changed function: ENTERSwitch
        function ENTERSwitchValueChanged(app, event)
           app.startupFcn
        end

        % Value changed function: EXITSwitch
        function EXITSwitchValueChanged(app, event)
            app.startupFcn
        end

        % Value changed function: COMPORTDropDown
        function COMPORTDropDownValueChanged(app, event)
            app.startupFcn
        end

        % Value changed function: BOARDDropDown
        function BOARDDropDownValueChanged(app, event)
            app.startupFcn
        end

        % Value changed function: SERVOPINDropDown
        function SERVOPINDropDownValueChanged(app, event)
            app.startupFcn
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.startupFcn
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.iNSTRUMENTATIONPANELPanel = uipanel(app.UIFigure);
            app.iNSTRUMENTATIONPANELPanel.Title = 'iNSTRUMENTATION PANEL';
            app.ENTERSwitchLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.ENTERSwitchLabel.Text = 'ENTER';
            app.ENTERSwitch = uiswitch(app.iNSTRUMENTATIONPANELPanel, 'toggle');
            app.ENTERSwitch.Items = {'OFF', 'ON'};
            app.ENTERSwitch.ValueChangedFcn = createCallbackFcn(app, @ENTERSwitchValueChanged, true);
            app.ENTERSwitch.Value = 'OFF';
            app.EXITSwitchLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.EXITSwitchLabel.Text = 'EXIT';
            app.EXITSwitch = uiswitch(app.iNSTRUMENTATIONPANELPanel, 'toggle');
            app.EXITSwitch.Items = {'OFF', 'ON'};
            app.EXITSwitch.ValueChangedFcn = createCallbackFcn(app, @EXITSwitchValueChanged, true);
            app.EXITSwitch.Value = 'OFF';
            app.STOPLampLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.STOPLampLabel.Text = 'STOP';
            app.STOPLamp = uilamp(app.iNSTRUMENTATIONPANELPanel);
            app.READYLampLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.READYLampLabel.Text = 'READY';
            app.READYLamp = uilamp(app.iNSTRUMENTATIONPANELPanel);
            app.GOLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.GOLabel.Text = '   GO';
            app.GOLamp = uilamp(app.iNSTRUMENTATIONPANELPanel);
            app.SERVOPOSGaugeLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.SERVOPOSGaugeLabel.Text = 'SERVO POS';
            app.SERVOPOSGauge = uigauge(app.iNSTRUMENTATIONPANELPanel, 'semicircular');
            app.EMERGENCYKNOBKnobLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.EMERGENCYKNOBKnobLabel.Text = 'EMERGENCY KNOB';
            app.EMERGENCYKNOBKnob = uiknob(app.iNSTRUMENTATIONPANELPanel, 'continuous');
            app.EMERGENCYKNOBKnob.ValueChangingFcn = createCallbackFcn(app, @EMERGENCYKNOBKnobValueChanging, true);
            app.ALERTMESSAGEEditFieldLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.ALERTMESSAGEEditFieldLabel.Text = 'ALERT MESSAGE';
            app.ALERTMESSAGEEditField = uieditfield(app.iNSTRUMENTATIONPANELPanel, 'text');
            app.AVAILABLESLOTSEditFieldLabel = uilabel(app.iNSTRUMENTATIONPANELPanel);
            app.AVAILABLESLOTSEditFieldLabel.Text = 'AVAILABLE SLOTS';
            app.AVAILABLESLOTSEditField = uieditfield(app.iNSTRUMENTATIONPANELPanel, 'text');
            app.SMARTPARKINGAPPLICATIONLabel = uilabel(app.UIFigure);
            app.SMARTPARKINGAPPLICATIONLabel.Text = 'SMART PARKING APPLICATION';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screenshot 2021-11-25 085407.png';
            app.CONFIGURATIONPANELPanel = uipanel(app.UIFigure);
            app.CONFIGURATIONPANELPanel.Title = 'CONFIGURATION PANEL';
            app.COMPORTDropDownLabel = uilabel(app.CONFIGURATIONPANELPanel);
            app.COMPORTDropDownLabel.Text = 'COM PORT';
            app.COMPORTDropDown = uidropdown(app.CONFIGURATIONPANELPanel);
            app.COMPORTDropDown.Items = {'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.COMPORTDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPORTDropDownValueChanged, true);
            app.COMPORTDropDown.Value = 'COM3';
            app.BOARDDropDownLabel = uilabel(app.CONFIGURATIONPANELPanel);
            app.BOARDDropDownLabel.Text = 'BOARD';
            app.BOARDDropDown = uidropdown(app.CONFIGURATIONPANELPanel);
            app.BOARDDropDown.Items = {'UNO', 'MEGA2560', 'LEORNADO'};
            app.BOARDDropDown.ValueChangedFcn = createCallbackFcn(app, @BOARDDropDownValueChanged, true);
            app.BOARDDropDown.Value = 'UNO';
            app.SERVOPINDropDownLabel = uilabel(app.CONFIGURATIONPANELPanel);
            app.SERVOPINDropDownLabel.Text = 'SERVO PIN';
            app.SERVOPINDropDown = uidropdown(app.CONFIGURATIONPANELPanel);
            app.SERVOPINDropDown.Items = {'D1', 'D2', 'D3', 'D4', 'D5', 'D6', 'D7', 'D8', 'D9', 'D10', 'D11', 'D12', 'D13'};
            app.SERVOPINDropDown.ValueChangedFcn = createCallbackFcn(app, @SERVOPINDropDownValueChanged, true);
            app.SERVOPINDropDown.Value = 'D1';
            app.ConnectButton = uibutton(app.CONFIGURATIONPANELPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_smart_parking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end